classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StateEditField       matlab.ui.control.EditField
        StateEditFieldLabel  matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end


    properties (Access = private)
        img_cross = imread('C:\Users\Peter\Desktop\CGX_git\img\cross.png');
        img_right = imread('C:\Users\Peter\Desktop\CGX_git\img\right.png');
        img_left  = imread('C:\Users\Peter\Desktop\CGX_git\img\left.png');
        img_black = imread('C:\Users\Peter\Desktop\CGX_git\img\black.png');
        mouse
    end
    
    methods (Access = public)
        
        function results = push_marker_1(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 290);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
            
        end
        
        function results = push_marker_2(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 317);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
          
        end
        function results = push_marker_3(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1705, 345);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
          
        end

        function results = Record_start(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;

            app.mouse.mouseMove(1827, 60);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.mouse.mouseMove(1770, 240);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.push_marker_3();
          
        end

        function results = Record_stop(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1655, 58);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release

            app.mouse.mouseMove(1710, 360);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
        end

        % Button pushed function: StartButton
        function Push_start_bttn(app, event)
            app.Record_start(); pause(2)

            app.Image.ImageSource = app.img_cross; app.StateEditField.Value = "Cross"; 
            pause(2)
            app.Image.ImageSource = app.img_right; app.StateEditField.Value = "Right"; app.push_marker_1();
            pause(1.25)
            app.Image.ImageSource = app.img_cross; app.StateEditField.Value = "Cross"; 
            pause(3)
            app.Image.ImageSource = app.img_black; app.StateEditField.Value = "None";
            pause(2)

            app.Image.ImageSource = app.img_cross; app.StateEditField.Value = "Cross"; 
            pause(2)
            app.Image.ImageSource = app.img_left; app.StateEditField.Value = "Left"; app.push_marker_2();
            pause(1.25)
            app.Image.ImageSource = app.img_cross; app.StateEditField.Value = "Cross"; 
            pause(3)
            app.Image.ImageSource = app.img_black; app.StateEditField.Value = "None"; 
            pause(2)

            app.Record_stop();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'black.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Push_start_bttn, true);
            app.StartButton.Text = 'Start';
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end